classdef ADCS_program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        logo_2                matlab.ui.control.Image
        logo                  matlab.ui.control.Image
        run_button            matlab.ui.control.Button
        text20                matlab.ui.control.Label
        uibuttongroup7        matlab.ui.container.ButtonGroup
        compCost_text         matlab.ui.control.Label
        ascct_text            matlab.ui.control.Label
        eulerint_text         matlab.ui.control.Label
        settime_text          matlab.ui.control.Label
        text11                matlab.ui.control.Label
        text10                matlab.ui.control.Label
        text9                 matlab.ui.control.Label
        text8                 matlab.ui.control.Label
        uibuttongroup6        matlab.ui.container.ButtonGroup
        controller_button     matlab.ui.control.Button
        controller_popupmenu  matlab.ui.control.DropDown
        uibuttongroup5        matlab.ui.container.ButtonGroup
        perturbation_button   matlab.ui.control.Button
        cubesat_button        matlab.ui.control.Button
        uibuttongroup2        matlab.ui.container.ButtonGroup
        text19                matlab.ui.control.Label
        text18                matlab.ui.control.Label
        text17                matlab.ui.control.Label
        text6                 matlab.ui.control.Label
        text5                 matlab.ui.control.Label
        text4                 matlab.ui.control.Label
        step_edit             matlab.ui.control.EditField
        finalTime_edit        matlab.ui.control.EditField
        initTime_edit         matlab.ui.control.EditField
        uibuttongroup1        matlab.ui.container.ButtonGroup
        graphic2_popupmenu    matlab.ui.control.DropDown
        text3                 matlab.ui.control.Label
        graphic2_button       matlab.ui.control.Button
        graphic2_axes         matlab.ui.control.UIAxes
        uipanel1              matlab.ui.container.Panel
        graphic1_button       matlab.ui.control.Button
        text2                 matlab.ui.control.Label
        graphic1_popupmenu    matlab.ui.control.DropDown
        graphic1_axes         matlab.ui.control.UIAxes
        text16                matlab.ui.control.Label
    end

    
    properties (Access = private)
        callSatParameters % Description
        callDisturbances
    end
    
    properties (Access = public)
       simulationParameters
    end
    
    methods (Access = private)
        function parameters = initializeSimulationParameters(~)
            %CubeSat Parameters
            parameters.initialValues.I = [1,2,3;4,5,6;7,8,9];
            parameters.initialValues.q0 =[1,0,0,0]';
            parameters.initialValues.Wo =[0,0,0]';
            %Controller Parameters
            parameters.setPoint.qd =[0.9808,0.1691,0.0933,0.0277]';
            parameters.setPoint.Wd =[0,0,0]'; %rad/s
            %Feedback Controller parameters
            parameters.feedback.Keye = 2*eye(3);
            parameters.feedback.Peye = 3*eye(3);
            %Boskovic Controller parameters
            parameters.boskController.delta = 0.01;
            parameters.boskController.gamma = 0.001;
            parameters.boskController.Umax  = 0.10;
            parameters.boskController.k0    = 1;
            %Disturbances torque parameters
            parameters.disturbances.constant.Tdx = 0.001;
            parameters.disturbances.constant.Tdy = 0.001;
            parameters.disturbances.constant.Tdz = 0.001;
            parameters.disturbances.variable.A   = 0.01;
            parameters.disturbances.variable.w1  = 1.00;
            parameters.disturbances.variable.w2  = 2.00;
            parameters.disturbances.variable.w3  = 3.00;
        end
    end
    
    methods (Access = public)
        
        function importData(app, receivedData)
           switch  receivedData.i
               case 1
                app.simulationParameters.initialValues.I  = receivedData.I;
                app.simulationParameters.initialValues.q0 = receivedData.q0';
                app.simulationParameters.initialValues.Wo = receivedData.Wo;  
                msgbox("Initial conditions successfully modified","Success");
               case 2
                app.simulationParameters.disturbances.constant.Tdx = receivedData.Tdx;
                app.simulationParameters.disturbances.constant.Tdy = receivedData.Tdy;
                app.simulationParameters.disturbances.constant.Tdz = receivedData.Tdz;
                msgbox("Constant disturbances modified","Success");
                case 3
                app.simulationParameters.disturbances.variable.A = receivedData.A;
                app.simulationParameters.disturbances.variable.w1 = receivedData.w1;
                app.simulationParameters.disturbances.variable.w2 = receivedData.w2;
                app.simulationParameters.disturbances.variable.w3 = receivedData.w3;
                msgbox("Variable disturbances modified","Success");
           end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simulationParameters = initializeSimulationParameters(app);
        end

        % Button pushed function: cubesat_button
        function cubesat_buttonButtonPushed(app, event)
            app.callSatParameters = cubesatSettings(app,app.simulationParameters.initialValues);
        end

        % Button pushed function: perturbation_button
        function perturbation_buttonButtonPushed(app, event)
            app.callDisturbances = perturbationSettings(app,app.simulationParameters.disturbances);
        end

        % Button pushed function: controller_button
        function controller_buttonButtonPushed(app, event)
            v = app.controller_popupmenu.ValueIndex;
            switch v
                case 1
                    feedbackSettingd(app,app.simulationParameters.setPoint,app.simulationParameters.feedback);
                case 2
                    %BoskSettings(handles.qd, handles.Wd, handles.delta, handles.gamma,...
                    %    handles.Umax, handles.k0);
                    msgbox("8 disturbances modified","Success");
                otherwise
                    disp('other value')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.text16 = uilabel(app.UIFigure);
            app.text16.Tag = 'text16';
            app.text16.Text = 'CubeSats Control Attitude Evaluation';
            app.uipanel1 = uipanel(app.UIFigure);
            app.uipanel1.Title = 'Graph 1';
            app.uipanel1.Tag = 'uipanel1';
            app.graphic1_axes = uiaxes(app.uipanel1);
            app.graphic1_axes.Tag = 'graphic1_axes';
            app.graphic1_popupmenu = uidropdown(app.uipanel1);
            app.graphic1_popupmenu.Items = {'Euler Angles (ZYX)', 'Quaternions', 'Angular Rates', 'Perturbations', 'Control Torque', 'EULERINT', 'ASCCT', 'K'};
            app.graphic1_popupmenu.Tag = 'graphic1_popupmenu';
            app.graphic1_popupmenu.Value = 'Euler Angles (ZYX)';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select Parameter:';
            app.graphic1_button = uibutton(app.uipanel1, 'push');
            app.graphic1_button.Tag = 'graphic1_button';
            app.graphic1_button.Enable = 'off';
            app.graphic1_button.Text = 'Graph';
            app.uibuttongroup1 = uibuttongroup(app.UIFigure);
            app.uibuttongroup1.Title = 'Graph 2';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.graphic2_axes = uiaxes(app.uibuttongroup1);
            app.graphic2_axes.Tag = 'graphic2_axes';
            app.graphic2_button = uibutton(app.uibuttongroup1, 'push');
            app.graphic2_button.Tag = 'graphic2_button';
            app.graphic2_button.Enable = 'off';
            app.graphic2_button.Text = 'Graph';
            app.text3 = uilabel(app.uibuttongroup1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Select Parameter:';
            app.graphic2_popupmenu = uidropdown(app.uibuttongroup1);
            app.graphic2_popupmenu.Items = {'Euler Angles (ZYX)', 'Quaternions', 'Angular Rates', 'Perturbations', 'Control Torque', 'EULERINT', 'ASCCT', 'K'};
            app.graphic2_popupmenu.Tag = 'graphic2_popupmenu';
            app.graphic2_popupmenu.Value = 'Euler Angles (ZYX)';
            app.uibuttongroup2 = uibuttongroup(app.UIFigure);
            app.uibuttongroup2.Title = 'Simulation Parameters';
            app.uibuttongroup2.Tag = 'uibuttongroup2';
            app.initTime_edit = uieditfield(app.uibuttongroup2, 'text');
            app.initTime_edit.Tag = 'initTime_edit';
            app.initTime_edit.Value = '0.0';
            app.finalTime_edit = uieditfield(app.uibuttongroup2, 'text');
            app.finalTime_edit.Tag = 'finalTime_edit';
            app.finalTime_edit.Value = '10.0';
            app.step_edit = uieditfield(app.uibuttongroup2, 'text');
            app.step_edit.Tag = 'step_edit';
            app.step_edit.Value = '1E-3';
            app.text4 = uilabel(app.uibuttongroup2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Initial Time:';
            app.text5 = uilabel(app.uibuttongroup2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Final Time:';
            app.text6 = uilabel(app.uibuttongroup2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Step:';
            app.text17 = uilabel(app.uibuttongroup2);
            app.text17.Tag = 'text17';
            app.text17.Text = 's';
            app.text18 = uilabel(app.uibuttongroup2);
            app.text18.Tag = 'text18';
            app.text18.Text = 's';
            app.text19 = uilabel(app.uibuttongroup2);
            app.text19.Tag = 'text19';
            app.text19.Text = 's';
            app.uibuttongroup5 = uibuttongroup(app.UIFigure);
            app.uibuttongroup5.Title = 'Model Setings';
            app.uibuttongroup5.Tag = 'uibuttongroup5';
            app.cubesat_button = uibutton(app.uibuttongroup5, 'push');
            app.cubesat_button.ButtonPushedFcn = createCallbackFcn(app, @cubesat_buttonButtonPushed, true);
            app.cubesat_button.Tag = 'cubesat_button';
            app.cubesat_button.Text = 'CubeSat Parameters';
            app.perturbation_button = uibutton(app.uibuttongroup5, 'push');
            app.perturbation_button.ButtonPushedFcn = createCallbackFcn(app, @perturbation_buttonButtonPushed, true);
            app.perturbation_button.Tag = 'perturbation_button';
            app.perturbation_button.Text = 'Perturbations Settings';
            app.uibuttongroup6 = uibuttongroup(app.UIFigure);
            app.uibuttongroup6.Title = 'Controller';
            app.uibuttongroup6.Tag = 'uibuttongroup6';
            app.controller_popupmenu = uidropdown(app.uibuttongroup6);
            app.controller_popupmenu.Items = {'Feedbak Controller', 'Boskovic''s Controller'};
            app.controller_popupmenu.Tag = 'controller_popupmenu';
            app.controller_popupmenu.Value = 'Feedbak Controller';
            app.controller_button = uibutton(app.uibuttongroup6, 'push');
            app.controller_button.ButtonPushedFcn = createCallbackFcn(app, @controller_buttonButtonPushed, true);
            app.controller_button.Tag = 'controller_button';
            app.controller_button.Text = 'Settings';
            app.uibuttongroup7 = uibuttongroup(app.UIFigure);
            app.uibuttongroup7.Title = 'Controller Performance';
            app.uibuttongroup7.Tag = 'uibuttongroup7';
            app.text8 = uilabel(app.uibuttongroup7);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Settlement time (5%):';
            app.text9 = uilabel(app.uibuttongroup7);
            app.text9.Tag = 'text9';
            app.text9.Text = 'EULERINT:';
            app.text10 = uilabel(app.uibuttongroup7);
            app.text10.Tag = 'text10';
            app.text10.Text = 'ASCCT:';
            app.text11 = uilabel(app.uibuttongroup7);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Computational Cost (O):';
            app.settime_text = uilabel(app.uibuttongroup7);
            app.settime_text.Tag = 'settime_text';
            app.settime_text.Text = '0.0';
            app.eulerint_text = uilabel(app.uibuttongroup7);
            app.eulerint_text.Tag = 'eulerint_text';
            app.eulerint_text.Text = '0.0';
            app.ascct_text = uilabel(app.uibuttongroup7);
            app.ascct_text.Tag = 'ascct_text';
            app.ascct_text.Text = '0.0';
            app.compCost_text = uilabel(app.uibuttongroup7);
            app.compCost_text.Tag = 'compCost_text';
            app.compCost_text.Text = '0.0';
            app.text20 = uilabel(app.UIFigure);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Convenio/Contrato N° PE501079885-2022-PROCIENCIA';
            app.run_button = uibutton(app.UIFigure, 'push');
            app.run_button.Tag = 'run_button';
            app.run_button.Text = 'Run';
            app.logo = uiimage(app.UIFigure);
            app.logo.ImageSource = fullfile(pathToMLAPP, 'adcs.png');
            app.logo_2 = uiimage(app.UIFigure);
            app.logo_2.ImageSource = fullfile(pathToMLAPP, 'adcs.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCS_program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end